classdef stethoscope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Cutoff1DropDown          matlab.ui.control.DropDown
        Cutoff1DropDownLabel     matlab.ui.control.Label
        orderDropDown            matlab.ui.control.DropDown
        orderDropDownLabel       matlab.ui.control.Label
        Button                   matlab.ui.control.Button
        StatusLabel              matlab.ui.control.Label
        Lamp                     matlab.ui.control.Lamp
        LampLabel                matlab.ui.control.Label
        HeartbeatEditField       matlab.ui.control.NumericEditField
        HeartbeatEditFieldLabel  matlab.ui.control.Label
        XLabel                   matlab.ui.control.Label
        NameEditField            matlab.ui.control.EditField
        NameEditFieldLabel       matlab.ui.control.Label
        OkButton                 matlab.ui.control.Button
        HeartbeatLabel           matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes6                  matlab.ui.control.UIAxes
        UIAxes5                  matlab.ui.control.UIAxes
        UIAxes4                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        order = 6; % Description
        cutoff1 = 20; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Button, HeartbeatEditField
        function ButtonPushed(app, event)
% Real Time Plot
a = arduino('com14', 'uno');
interv = 210;
windowLength = 200;  % Sabit pencere uzunluğu
x = zeros(1, windowLength);  % Başlangıçta sıfırlarla doldurulmuş vektör
init_time = 0;

while (init_time < interv)
    b = readVoltage(a, 'A1');
    
    % Yeni ölçümü vektöre ekle
    x = [x(2:end), b];
    
    % Gerçek zamanlı plot
    plot(app.UIAxes, x)
    
    % FFT
    Fs = 2000;x;
    L = length(x);
    f = Fs * (0:(L/2)) / L;
    Y = fft(x);
    P2 = abs(Y / L);
    P1 = P2(1:L/2+1);
    P1(2:end-1) = 2 * P1(2:end-1);
    
    % Uygun uzunluktaki f vektörünü oluştur
    f = f(1:length(P1));

    % FFT'yi görselleştir
    plot(app.UIAxes6, f, P1);
    title(app.UIAxes6, "FFT of the signal");
    xlabel(app.UIAxes6, "f (Hz)");
    ylabel(app.UIAxes6, "|P1(f)|");
    
    % Band-Pass Filter
    fs = Fs;
    bp = designfilt('bandpassiir', 'FilterOrder', double(app.order), ...
        'HalfPowerFrequency1', max(0, double(app.cutoff1)), 'HalfPowerFrequency2', 1000, ...
        'SampleRate', fs);
    y = filter(bp, x);
    
    % Filtrelenmiş sinyali görselleştir
    plot(app.UIAxes5, y);
    
    % Filtrelenmiş sinyalin FFT'sini hesapla ve görselleştir
    Yf = fft(y);
    Pf2 = abs(Yf / L);
    Pf1 = Pf2(1:L/2+1);
    Pf1(2:end-1) = 2 * Pf1(2:end-1);
    plot(app.UIAxes4, f, Pf1);
    title(app.UIAxes4, "FFT of Filtered Signal");
    xlabel(app.UIAxes4, "f (Hz)");
    ylabel(app.UIAxes4, "|Pf1(f)|");
    
    init_time = init_time + 1;
    drawnow
    % Optional: pause(0.01); % Gerekirse ayarlayın
end
% Heart rate detection and sound playback
t1 = 1:length(y);
[pk,lk] = findpeaks(y, t1, "MinPeakHeight", 0.011);
m = length([pk, lk]) / 2;
app.HeartbeatEditField.Value = m;

if m > 60
    app.StatusLabel.Text = 'Normal';
     app.Lamp.Color = [0, 1, 0];
    [y, Ffs] = audioread('C:stethescope\normal.opus');
    sound(y, Ffs);

elseif (40 < m) && (m < 60)
    app.StatusLabel.Text = 'Normal';
    app.Lamp.Color = [0, 1, 0];
    [y, Ffs] = audioread('C:stethescope\normal.opus');
    sound(y, Ffs);

elseif m < 40
    app.StatusLabel.Text = 'Normal';
     app.Lamp.Color = [0, 1, 0];
    [y, Ffs] = audioread('C:stethescope\normal.opus');
    sound(y, Ffs);
end
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            
            
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            value = app.NameEditField.Value;
                    app.XLabel.Text= value;
                        value = 'enter your name';
        end

        % Value changed function: orderDropDown
        function orderDropDownValueChanged(app, event)
            value = app.orderDropDown.Value;
            app.order = value;
            value = double(value);
            
            
        end

        % Value changed function: Cutoff1DropDown
        function Cutoff1DropDownValueChanged(app, event)
            value = app.Cutoff1DropDown.Value;
            app.cutoff1 = str2double(value);
             % disp(str2double(value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'FFT of filtered signal')
            xlabel(app.UIAxes4, 'f (Hz)')
            ylabel(app.UIAxes4, '|Pf1(f)|')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Filtered signal')
            xlabel(app.UIAxes5, 'f (Hz)')
            ylabel(app.UIAxes5, '|Pf1(f)|')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'FFT of the signal')
            xlabel(app.UIAxes6, 'f (Hz)')
            ylabel(app.UIAxes6, '|Pf1(f)|')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'RAW Signal')
            xlabel(app.UIAxes, 'f (Hz)')
            ylabel(app.UIAxes, '|Pf1(f)|')
            zlabel(app.UIAxes, 'Z')
            app.HeartbeatLabel = uilabel(app.UIFigure);
            app.HeartbeatLabel.Text = 'Heartbeat';
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'NameEditField';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'XLabel';
            app.HeartbeatEditFieldLabel = uilabel(app.UIFigure);
            app.HeartbeatEditFieldLabel.Text = 'HeartbeatEditField';
            app.HeartbeatEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeartbeatEditField.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'StatusLabel';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.orderDropDownLabel = uilabel(app.UIFigure);
            app.orderDropDownLabel.Text = 'order';
            app.orderDropDown = uidropdown(app.UIFigure);
            app.orderDropDown.Items = {'2', '4', '6', '8'};
            app.orderDropDown.ValueChangedFcn = createCallbackFcn(app, @orderDropDownValueChanged, true);
            app.orderDropDown.Value = '6';
            app.Cutoff1DropDownLabel = uilabel(app.UIFigure);
            app.Cutoff1DropDownLabel.Text = 'Cutoff1';
            app.Cutoff1DropDown = uidropdown(app.UIFigure);
            app.Cutoff1DropDown.Items = {'20', '40', '50', '100', '200', '300', '400', '500', '600'};
            app.Cutoff1DropDown.ValueChangedFcn = createCallbackFcn(app, @Cutoff1DropDownValueChanged, true);
            app.Cutoff1DropDown.Value = '20';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stethoscope

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end